classdef CRTNCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OutputsLabel                    matlab.ui.control.Label
        InputsLabel                     matlab.ui.control.Label
        DistancefromgroundMEditField    matlab.ui.control.NumericEditField
        DistancefromgroundMEditFieldLabel  matlab.ui.control.Label
        DistancefromroadMEditField      matlab.ui.control.NumericEditField
        DistancefromroadMEditFieldLabel  matlab.ui.control.Label
        AverageVelocityKmhEditField     matlab.ui.control.NumericEditField
        AverageVelocityKmhEditFieldLabel  matlab.ui.control.Label
        NumberofHGVEditFieldLabel       matlab.ui.control.Label
        NumberofHGVEditField            matlab.ui.control.NumericEditField
        CRTNCalculatorLabel             matlab.ui.control.Label
        BasicLA1018hEditFieldLabel      matlab.ui.control.Label
        BasicLA1018hEditField           matlab.ui.control.NumericEditField
        BasicLA101hEditField            matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        NumberofvehiclesEditFieldLabel  matlab.ui.control.Label
        NumberofvehiclesEditField       matlab.ui.control.NumericEditField
        MinutesEditField                matlab.ui.control.NumericEditField
        MinutesEditFieldLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
         CarInput = app.NumberofvehiclesEditField.Value; 

         MinInput = app.MinutesEditField.Value;
         HrCalc = 60/MinInput;

         CarPerHr = CarInput*HrCalc;

         LA101h = 42.2  + (10*log10(CarPerHr));

         app.BasicLA101hEditField.Value = LA101h;

         CarPer18Hr = CarPerHr*18;

         LA1018hr = 29.1 + (10*log10(CarPer18Hr));

         app.BasicLA1018hEditField.Value = LA1018hr;

        
        V = app.AverageVelocityKmhEditField.Value;
        P = app.NumberofHGVEditField.Value;


        correction = 33 * log10 (V + 40 + (500 / V)) + 10*log (1 + 5*P / V) - 68.8;

    LA101h = LA101h + correction;
    LA1018hr = LA1018hr + correction;
        
app.NumberofHGVEditField.Value = app.NumberofHGVEditField.Value / app.NumberofvehiclesEditField.Value * 100;
        

   app.BasicLA1018hEditField.Value = LA1018hr;

app.BasicLA101hEditField.Value = LA101h;

Dinput = app.DistancefromroadMEditField.Value;
H = app.DistancefromgroundMEditField.Value;

D = sqrt((Dinput + 3.5)^2 + H^2);

Dpluss = -10 * log10(D/13.5);

correction2 = Dpluss + LA101h;

LA101h = correction2;

correction3 = Dpluss + LA1018hr;

LA1018hr = correction3;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MinutesEditFieldLabel = uilabel(app.UIFigure);
            app.MinutesEditFieldLabel.Text = 'Minutes';
            app.MinutesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofvehiclesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofvehiclesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofvehiclesEditFieldLabel.Text = 'Number of vehicles ';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Basic LA10 1h';
            app.BasicLA101hEditField = uieditfield(app.UIFigure, 'numeric');
            app.BasicLA1018hEditField = uieditfield(app.UIFigure, 'numeric');
            app.BasicLA1018hEditFieldLabel = uilabel(app.UIFigure);
            app.BasicLA1018hEditFieldLabel.Text = 'Basic LA10 18h';
            app.CRTNCalculatorLabel = uilabel(app.UIFigure);
            app.CRTNCalculatorLabel.Text = 'CRTN Calculator';
            app.NumberofHGVEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofHGVEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofHGVEditFieldLabel.Text = 'Number of HGV';
            app.AverageVelocityKmhEditFieldLabel = uilabel(app.UIFigure);
            app.AverageVelocityKmhEditFieldLabel.Text = 'Average Velocity (Kmh)';
            app.AverageVelocityKmhEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancefromroadMEditFieldLabel = uilabel(app.UIFigure);
            app.DistancefromroadMEditFieldLabel.Text = 'Distance from roadM';
            app.DistancefromroadMEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancefromgroundMEditFieldLabel = uilabel(app.UIFigure);
            app.DistancefromgroundMEditFieldLabel.Text = 'Distance from groundM';
            app.DistancefromgroundMEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.OutputsLabel = uilabel(app.UIFigure);
            app.OutputsLabel.Text = 'Outputs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CRTNCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end