classdef Rt60 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        KhzEditField                  matlab.ui.control.NumericEditField
        KhzEditFieldLabel             matlab.ui.control.Label
        KHzEditField_2                matlab.ui.control.NumericEditField
        KHzEditField_2Label           matlab.ui.control.Label
        KHzEditField                  matlab.ui.control.NumericEditField
        KHzEditFieldLabel             matlab.ui.control.Label
        HzEditField_3                 matlab.ui.control.NumericEditField
        HzEditField_3Label            matlab.ui.control.Label
        HzEditField_2                 matlab.ui.control.NumericEditField
        HzEditField_2Label            matlab.ui.control.Label
        HzEditField                   matlab.ui.control.NumericEditField
        HzEditFieldLabel              matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        RT60Label                     matlab.ui.control.Label
        RoomMaterialsLabel            matlab.ui.control.Label
        wallmaterialDropDown          matlab.ui.control.DropDown
        wallmaterialDropDownLabel     matlab.ui.control.Label
        ceilingmaterialDropDown       matlab.ui.control.DropDown
        ceilingmaterialDropDownLabel  matlab.ui.control.Label
        floormaterialDropDown         matlab.ui.control.DropDown
        floormaterialDropDownLabel    matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        widthEditField                matlab.ui.control.NumericEditField
        widthEditFieldLabel           matlab.ui.control.Label
        lengthEditField               matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: lengthEditField
        function lengthEditFieldValueChanged(app, event)
           value = app.lengthEditField.Value;
            
            
        end

        % Button pushed function: Button
        function ButtonPush(app, event)
            %Obtain user inputs from GUI
            RoomLength = app.lengthEditField.Value;
            RoomWidth = app.widthEditField.Value;
            RoomHeight = app.HeightEditField.Value;

            %calculate room volume
            volume = RoomLength * RoomWidth * RoomHeight;

            %calculate surface areas
            AreaLWall = RoomLength * RoomHeight;
           AreaRwall = RoomLength * RoomHeight;
           AreaBwall = RoomWidth * RoomHeight;
           AreaFwall = RoomWidth * RoomHeight;

           AreaCeiling = RoomLength * RoomWidth;
           Areafloor = RoomLength * RoomWidth;

           TotalArea = AreaLWall + AreaRwall + AreaBwall + Areafloor + AreaCeiling 

           %convert DropDown from string (text) to numbers

           materialWalls = app.wallmaterialDropDown.Value;
           MaterialsFloor = app.floormaterialDropDown.Value;
           MaterialsCeiling = app.ceilingmaterialDropDown.Value;

           %define absoprtion coeffiecients [125Hz 250Hz 500Hz 1000Hz
           %2000Hz 4000Hz
           Carpet = [0.10, 0.15, 0.25, 0.30, 0.30, 0.30];
           Plaster = [0.02, 0.02, 0.02, 0.02, 0.02, 0.02];
           PlasterCeilingTiles = [0.45, 0.70, 0.80, 0.80, 0.65, 0.45];
           Rockwool150mm = [0.15, 0.60, 0.90, 0.90, 0.90, 0.85];

           %turn arrays into a matrix 
           Matrix = [Carpet;Plaster; PlasterCeilingTiles; Rockwool150mm];

           %Get Coefficients based on DropDown options
           wallCoefficients = Matrix(materialWalls,:);
           FLoorCoefficients = Matrix(MaterialsFloor,:);
           CeilingCoefficients = Matrix(MaterialsCeiling);

           %Calculate equivalent absorption for each surface (sabin)
           SabLWall = wallCoefficients * AreaLWall;
             SabRWall = wallCoefficients * AreaRwall;
             SabFwall =  wallCoefficients * AreaFwall;
             SabBwall = wallCoefficients * AreaBwall; 
             SabCeiling = wallCoefficients * AreaCeiling;
             SabFLoor = wallCoefficients * Areafloor;

             %Calculate total room absorption (A)
             Totalabsorption = SabLWall + SabRWall + SabFLoor + SabCeiling + SabFwall + SabBwall;

             %Calculate Sbaine equation 
             Sabine = (0.161 * volume) ./ Totalabsorption;

             %DisplayAnswers

             app.HzEditField.Value = Sabine(1);
              app.HzEditField_2.Value = Sabine(2);
               app.HzEditField_3.Value = Sabine(3);
               
                 app.KHzEditField.Value = Sabine(4);
                  app.kHzEditField_2.Value = Sabine(5);
                  app.kHzEditField_3.Value = Sabine(6);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'length ';
            app.lengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.lengthEditField.ValueChangedFcn = createCallbackFcn(app, @lengthEditFieldValueChanged, true);
            app.widthEditFieldLabel = uilabel(app.UIFigure);
            app.widthEditFieldLabel.Text = 'width ';
            app.widthEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.floormaterialDropDownLabel = uilabel(app.UIFigure);
            app.floormaterialDropDownLabel.Text = 'floor material';
            app.floormaterialDropDown = uidropdown(app.UIFigure);
            app.floormaterialDropDown.Items = {'carpet', 'plaster', 'roof tiles', 'rockwool'};
            app.floormaterialDropDown.ItemsData = [1 2 3 4];
            app.floormaterialDropDown.Value = 1;
            app.ceilingmaterialDropDownLabel = uilabel(app.UIFigure);
            app.ceilingmaterialDropDownLabel.Text = 'ceiling material';
            app.ceilingmaterialDropDown = uidropdown(app.UIFigure);
            app.ceilingmaterialDropDown.Items = {'carpet ', 'plaster', 'roof tiles ', 'rockwool'};
            app.ceilingmaterialDropDown.ItemsData = [1 2 3 4];
            app.ceilingmaterialDropDown.Value = 1;
            app.wallmaterialDropDownLabel = uilabel(app.UIFigure);
            app.wallmaterialDropDownLabel.Text = 'wall material ';
            app.wallmaterialDropDown = uidropdown(app.UIFigure);
            app.wallmaterialDropDown.Items = {'Carpet,', 'Plaster,', 'Roof tiles, ', 'rockwool'};
            app.wallmaterialDropDown.ItemsData = [1 2 3 4];
            app.wallmaterialDropDown.Value = 1;
            app.RoomMaterialsLabel = uilabel(app.UIFigure);
            app.RoomMaterialsLabel.Text = 'Room Materials';
            app.RT60Label = uilabel(app.UIFigure);
            app.RT60Label.Text = 'RT60';
            app.Label_2 = uilabel(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPush, true);
            app.HzEditFieldLabel = uilabel(app.UIFigure);
            app.HzEditFieldLabel.Text = '125Hz';
            app.HzEditField = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_2Label = uilabel(app.UIFigure);
            app.HzEditField_2Label.Text = '250Hz';
            app.HzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_3Label = uilabel(app.UIFigure);
            app.HzEditField_3Label.Text = '500Hz';
            app.HzEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.KHzEditFieldLabel = uilabel(app.UIFigure);
            app.KHzEditFieldLabel.Text = '1KHz';
            app.KHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.KHzEditField_2Label = uilabel(app.UIFigure);
            app.KHzEditField_2Label.Text = '2KHz';
            app.KHzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.KhzEditFieldLabel = uilabel(app.UIFigure);
            app.KhzEditFieldLabel.Text = '4Khz';
            app.KhzEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rt60

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end